classdef Anser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure  matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SetupTab                     matlab.ui.container.Tab
        ChannelselectPanel           matlab.ui.container.Panel
        ChannelSelectListBox         matlab.ui.control.ListBox
        HoldCtrltoselectmultiplechannelsLabel  matlab.ui.control.Label
        SystemconfigurationPanel     matlab.ui.container.Panel
        SystemversionButtonGroup     matlab.ui.container.ButtonGroup
        AnserButton                  matlab.ui.control.RadioButton
        DAQversionButtonGroup        matlab.ui.container.ButtonGroup
        NI621XButton                 matlab.ui.control.RadioButton
        MCC1608GXButton              matlab.ui.control.RadioButton
        BoardIDPanel                 matlab.ui.container.Panel
        EditField                    matlab.ui.control.EditField
        egDev1Board1etcLabel         matlab.ui.control.Label
        ConnectButton_2              matlab.ui.control.Button
        TextArea_2                   matlab.ui.control.TextArea
        AdvancedPanel_3              matlab.ui.container.Panel
        SamplesizeSpinnerLabel       matlab.ui.control.Label
        SamplesizeSpinner            matlab.ui.control.Spinner
        CalibrationTab               matlab.ui.container.Tab
        CalibrationsettingsPanel     matlab.ui.container.Panel
        SensorselectDropDown_4Label  matlab.ui.control.Label
        SensorselectDropDown_4       matlab.ui.control.DropDown
        CalibrateButton              matlab.ui.control.Button
        AdvancedPanel_2              matlab.ui.container.Panel
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Testpointheight01mmLabel     matlab.ui.control.Label
        RecalibrateButton            matlab.ui.control.Button
        CalibrationstatusPanel       matlab.ui.container.Panel
        TextArea_3                   matlab.ui.control.TextArea
        TrackingTab_2                matlab.ui.container.Tab
        TrackingconfigurationPanel   matlab.ui.container.Panel
        TargetspeedHzSpinnerLabel    matlab.ui.control.Label
        TargetspeedHzSpinner         matlab.ui.control.Spinner
        StarttrackingButton          matlab.ui.control.StateButton
        StatusTextAreaLabel          matlab.ui.control.Label
        StatusTextArea               matlab.ui.control.TextArea
        StoptrackingButton           matlab.ui.control.Button
        TrackinginformationPanel     matlab.ui.container.Panel
        TrackingFrequency            matlab.ui.control.NumericEditField
        SensorselectDropDown_7Label  matlab.ui.control.Label
        SensorselectDropDown_7       matlab.ui.control.DropDown
        XmmEditFieldLabel            matlab.ui.control.Label
        XmmEditField                 matlab.ui.control.NumericEditField
        YmmEditFieldLabel            matlab.ui.control.Label
        YmmEditField                 matlab.ui.control.NumericEditField
        ZmmEditFieldLabel            matlab.ui.control.Label
        ZmmEditField                 matlab.ui.control.NumericEditField
        radiansEditFieldLabel        matlab.ui.control.Label
        radiansEditField             matlab.ui.control.NumericEditField
        radiansEditField_2Label      matlab.ui.control.Label
        radiansEditField_2           matlab.ui.control.NumericEditField
        TrackingfrequencyHzLabel     matlab.ui.control.Label
        OpenIGTLinkTab_2             matlab.ui.container.Tab
        NetworksettingsPanel         matlab.ui.container.Panel
        AddressEditFieldLabel        matlab.ui.control.Label
        AddressEditField             matlab.ui.control.EditField
        PortEditFieldLabel           matlab.ui.control.Label
        PortEditField                matlab.ui.control.NumericEditField
        StreamButton                 matlab.ui.control.Button
        ConnectButton_3              matlab.ui.control.StateButton
        ConnectionstatusPanel        matlab.ui.container.Panel
        TextArea_4                   matlab.ui.control.TextArea
        ApplytransformPanel          matlab.ui.container.Panel
        UITable_3                    matlab.ui.control.Table
        SensorselectDropDown_8Label  matlab.ui.control.Label
        SensorselectDropDown_8       matlab.ui.control.DropDown
        FlipZButton                  matlab.ui.control.Button
        ToolsTab                     matlab.ui.container.Tab
        TabGroup2                    matlab.ui.container.TabGroup
        SampleTab_2                  matlab.ui.container.Tab
        FFTTab                       matlab.ui.container.Tab
        PositionTab                  matlab.ui.container.Tab
        AboutTab                     matlab.ui.container.Tab
        Label_3                      matlab.ui.control.Label
        Label_4                      matlab.ui.control.Label
        Label2                       matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        AnserEMTLabel                matlab.ui.control.Label
        AgraphicaluserinterfacefortheAnserEMTplatformLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        sys = struct(); % Description
    end

    properties (Access = private)
        sensors = [] % Description
        igtlconnection = struct(); % Description
        flipvector = zeros(1, 16) % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function AnserStartupFcn(app)
            app.UITable_3.Data = eye(4);
        end

        % Close request function: 
        % AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure
        function AnserCloseRequest(app, event)
            app.StarttrackingButton.Value = 0;
            if isempty(fieldnames(app.igtlconnection)) == 0
                igtlDisconnect(app.igtlconnection);
            end
            delete(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.sys.ztestpoint = app.sys.ztestpoint - 0.0001;
            app.TextArea_3.Value = strcat('Test point height:',{' '},num2str(app.sys.ztestpoint(1)*1000));
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.sys.ztestpoint = app.sys.ztestpoint + 0.0001;
            app.TextArea_3.Value = strcat('Test point height:',{' '},num2str(app.sys.ztestpoint(1)*1000));
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            % Stop tracking
            app.StarttrackingButton.Value;
            % Determine selected DAQ
            if app.NI621XButton.Value == 1
                daqtype = 'nidaq621Xoem';
            elseif app.MCC1608GXButton.Value == 1
                daqtype = 'mccdaq';
            end
            
            % Determine selected System Type
            if app.AnserButton.Value == 1
                systemtype = 'anser1';
            end
                        
            % Determine board ID
            boardid = app.EditField.Value;
            samplesize = 5000;
            
            % Initialise system
            try
                app.sys = fSysSetup(app.sensors,systemtype, daqtype, boardid, samplesize);
                tempstr = strcat(app.TextArea_3.Value,{' '},'Starting calibration, view commandline for instructions');
                app.TextArea_3.Value = tempstr; 
                
                app.sys = fSysSensor(app.sys, str2num(app.SensorselectDropDown_4.Value));
                app.sys = fGetCalField(app.sys);
                app.sys = fCalibrate(app.sys);
            
                rmsError = fCheck(app.sys);
                tempstr = strcat('Calibration complete, RMS Error: ',{' '},num2str(rmsError), 'mm');
                app.TextArea_3.Value = tempstr;
            
                app.sys = fSysSave(app.sys);
            catch error
                app.TextArea_3.Value = error; 
            end    
        end

        % Button pushed function: ConnectButton_2
        function ConnectButton_2Pushed(app, event)
            % Stop tracking;
            app.StarttrackingButton.Value = 0;
            
            chlist = cell2mat(app.ChannelSelectListBox.Value)';
            chlist = str2num(chlist);
            chlist_string = num2str(chlist);
            app.TextArea_2.Value = strcat('Channels selected: ',{' '},strjoin(app.ChannelSelectListBox.Value,', '));
            
            % Determine selected DAQ
            if app.NI621XButton.Value == 1
                daqtype = 'nidaq621Xoem';
            elseif app.MCC1608GXButton.Value == 1
                daqtype = 'mccdaq';
            end
            
            % Determine selected System Type
            if app.AnserButton.Value == 1
                systemtype = 'anser1';
            end
            
                        
            % Determine board ID
            boardid = app.EditField.Value;
            samplesize = app.SamplesizeSpinner.Value;
            
           
            % Initialise system
            try
                app.sys = fSysSetup(chlist,systemtype, daqtype, boardid, samplesize);
                % Save sensor list locally
                app.sensors = app.sys.Sensors;
                if length(app.sensors) >= 1
                    app.StarttrackingButton.Enable = 'on';
                end
                tempstr = app.TextArea_2.Value;
                tempstr = strcat(app.TextArea_2.Value,{' '},'Success!');
                app.TextArea_2.Value = tempstr; 
            catch
                app.TextArea_2.Value = 'Error initialising system'; 
            end


        end

        % Value changed function: ConnectButton_3
        function ConnectButton_3ValueChanged(app, event)
            value = app.ConnectButton_3.Value;
            if value == 1
                try
                    app.igtlconnection = igtlConnect(app.AddressEditField.Value, app.PortEditField.Value);
                    app.TextArea_4.Value = 'Connection Success!';
                    app.UITable_3.Data = eye(4);
                    app.ConnectButton_3.Text = 'Disconnect';
                catch
                    app.TextArea_4.Value = 'Connection Failed';
                    app.ConnectButton_3.Value = 0;
                end      
            else
                igtlDisconnect(app.igtlconnection);
                app.igtlconnection = struct();
                app.TextArea_4.Value = 'Connection Closed';
                app.ConnectButton_3.Text = 'Connect';
            end
            
            
        end

        % Button pushed function: RecalibrateButton
        function RecalibrateButtonPushed(app, event)
            app.sys = fCalibrate(app.sys);
            
            rmsError = fCheck(app.sys);
            tempstr = strcat('Calibration complete, RMS Error: ',{' '},num2str(rmsError), 'mm');
            app.TextArea_3.Value = tempstr;
        
            app.sys = fSysSave(app.sys);
        end

        % Callback function
        function SensorselectDropDown_2ValueChanged(app, event)
            value = app.SensorselectDropDown_2.Value;
            if isprop(app, 'sensors')
                if ismember(str2num(value),app.sys.Sensors)
                    app.AcquireButton.Enable = 'on';
                else
                    app.AcquireButton.Enable = 'off';
                end
            end
        end

        % Value changed function: SensorselectDropDown_4
        function SensorselectDropDown_4ValueChanged(app, event)
            value = app.SensorselectDropDown_4.Value;
            if isprop(app, 'sensors')
                if ismember(str2num(value),app.sys.Sensors)
                    app.CalibrateButton.Enable = 'on';
                    app.RecalibrateButton.Enable = 'on';
                else
                    app.CalibrateButton.Enable = 'off';
                    app.RecalibrateButton.Enable = 'off';
                end
            end
        end

        % Value changed function: SensorselectDropDown_7
        function SensorselectDropDown_7ValueChanged(app, event)
            value = app.SensorselectDropDown_7.Value;
            app.XmmEditField.Value = 0;
            app.YmmEditField.Value = 0;
            app.ZmmEditField.Value = 0;
            app.radiansEditField.Value  = 0;
            app.radiansEditField_2.Value = 0;
        end

        % Value changed function: StarttrackingButton
        function StarttrackingButtonValueChanged(app, event)
            value = app.StarttrackingButton.Value;
            app.StarttrackingButton.Enable = 'off';
            app.StoptrackingButton.Enable = 'on';
            
            sensorPositions = zeros(length(app.sensors),5);
            sensorMatrix    = zeros(4,4,length(app.sensors));
            
            while app.StarttrackingButton.Value == 1
               tStart = tic;
               
               app.sys = fSysDAQUpdate(app.sys);
               
               for i=1:length(app.sensors)
                   app.sys = fGetSensorPosition(app.sys,app.sensors(i));
                   sensorPositions(i,:) = app.sys.positionVector;
                   sensorPositions(i,4) = sensorPositions(i,4) + app.flipvector(i);
                   sensorMatrix(:,:,i) = fSphericalToMatrix(sensorPositions);
                   
                   if app.sensors(i) == str2num(app.SensorselectDropDown_7.Value)
                       app.XmmEditField.Value = sensorPositions(i,1)*1000;
                       app.YmmEditField.Value = sensorPositions(i,2)*1000;
                       app.ZmmEditField.Value = sensorPositions(i,3)*1000;
                       app.radiansEditField.Value  = sensorPositions(i,4);
                       app.radiansEditField_2.Value = sensorPositions(i,5);
                   end   
               end
               
               if isempty(fieldnames(app.igtlconnection)) == 0
                   transform = struct();
                   for i = 1:length(app.sensors)
                       transform.name = strcat('ProbeToTracker',num2str(app.sensors(i)));
                       transform.timestamp = igtlTimestampNow();
                       transform.matrix = sensorMatrix(:,:,i);
                       transform.matrix(1:3,4) = transform.matrix(1:3,4)*1000;
                       igtlSendTransform(app.igtlconnection,transform);
                   end
               end
            
               pause(1/app.TargetspeedHzSpinner.Value);
               tStop = toc(tStart);
               tFreq = 1/tStop;
            
               app.TrackingFrequency.Value = tFreq;
            end
            
            app.TrackingFrequency.Value = 0;
            
        end

        % Button pushed function: StoptrackingButton
        function StoptrackingButtonPushed(app, event)
            app.StarttrackingButton.Value = 0;
            app.StarttrackingButton.Enable = 'on';
            app.StoptrackingButton.Enable = 'off';
        end

        % Button pushed function: FlipZButton
        function FlipZButtonPushed(app, event)
            if app.flipvector(str2num(app.SensorselectDropDown_8.Value)) == 0
                app.flipvector(str2num(app.SensorselectDropDown_8.Value)) = pi;
            else
                app.flipvector(str2num(app.SensorselectDropDown_8.Value)) = 0;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure = uifigure;
            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure.Name = 'Anser EMT: An open source electromagnetic tracking platform';
            app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure.CloseRequestFcn = createCallbackFcn(app, @AnserCloseRequest, true);
            app.TabGroup = uitabgroup(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.ChannelselectPanel = uipanel(app.SetupTab);
            app.ChannelselectPanel.Title = 'Channel select';
            app.ChannelSelectListBox = uilistbox(app.ChannelselectPanel);
            app.ChannelSelectListBox.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.ChannelSelectListBox.Multiselect = 'on';
            app.ChannelSelectListBox.Value = {'1'};
            app.HoldCtrltoselectmultiplechannelsLabel = uilabel(app.ChannelselectPanel);
            app.HoldCtrltoselectmultiplechannelsLabel.Text = {'Hold <Ctrl> to select'; 'multiple channels'};
            app.SystemconfigurationPanel = uipanel(app.SetupTab);
            app.SystemconfigurationPanel.Title = 'System configuration';
            app.SystemversionButtonGroup = uibuttongroup(app.SystemconfigurationPanel);
            app.SystemversionButtonGroup.Title = 'System version';
            app.AnserButton = uiradiobutton(app.SystemversionButtonGroup);
            app.AnserButton.Text = 'Anser';
            app.AnserButton.Value = true;
            app.DAQversionButtonGroup = uibuttongroup(app.SystemconfigurationPanel);
            app.DAQversionButtonGroup.Title = 'DAQ version';
            app.NI621XButton = uiradiobutton(app.DAQversionButtonGroup);
            app.NI621XButton.Text = 'NI-621X';
            app.NI621XButton.Value = true;
            app.MCC1608GXButton = uiradiobutton(app.DAQversionButtonGroup);
            app.MCC1608GXButton.Text = 'MCC 1608GX';
            app.BoardIDPanel = uipanel(app.SystemconfigurationPanel);
            app.BoardIDPanel.Title = 'Board ID';
            app.EditField = uieditfield(app.BoardIDPanel, 'text');
            app.EditField.Value = 'Dev1';
            app.egDev1Board1etcLabel = uilabel(app.BoardIDPanel);
            app.egDev1Board1etcLabel.Text = '(eg. Dev1, Board1 etc.)';
            app.ConnectButton_2 = uibutton(app.SystemconfigurationPanel, 'push');
            app.ConnectButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConnectButton_2Pushed, true);
            app.ConnectButton_2.Text = 'Connect';
            app.TextArea_2 = uitextarea(app.SystemconfigurationPanel);
            app.TextArea_2.Editable = 'off';
            app.AdvancedPanel_3 = uipanel(app.SystemconfigurationPanel);
            app.AdvancedPanel_3.Title = 'Advanced';
            app.SamplesizeSpinnerLabel = uilabel(app.AdvancedPanel_3);
            app.SamplesizeSpinnerLabel.Text = 'Sample size';
            app.SamplesizeSpinner = uispinner(app.AdvancedPanel_3);
            app.SamplesizeSpinner.Step = 100;
            app.SamplesizeSpinner.Limits = [500 5000];
            app.SamplesizeSpinner.Value = 1000;
            app.CalibrationTab = uitab(app.TabGroup);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationsettingsPanel = uipanel(app.CalibrationTab);
            app.CalibrationsettingsPanel.Title = 'Calibration settings';
            app.SensorselectDropDown_4Label = uilabel(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4Label.Text = 'Sensor select';
            app.SensorselectDropDown_4 = uidropdown(app.CalibrationsettingsPanel);
            app.SensorselectDropDown_4.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SensorselectDropDown_4ValueChanged, true);
            app.SensorselectDropDown_4.Value = 'Select';
            app.CalibrateButton = uibutton(app.CalibrationsettingsPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.AdvancedPanel_2 = uipanel(app.CalibrationsettingsPanel);
            app.AdvancedPanel_2.Title = 'Advanced';
            app.Button_3 = uibutton(app.AdvancedPanel_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_2 = uibutton(app.AdvancedPanel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.Testpointheight01mmLabel = uilabel(app.AdvancedPanel_2);
            app.Testpointheight01mmLabel.Text = {'Testpoint height'; '(0.1mm)'};
            app.RecalibrateButton = uibutton(app.AdvancedPanel_2, 'push');
            app.RecalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalibrateButtonPushed, true);
            app.RecalibrateButton.Enable = 'off';
            app.RecalibrateButton.Text = 'Recalibrate';
            app.CalibrationstatusPanel = uipanel(app.CalibrationTab);
            app.CalibrationstatusPanel.Title = 'Calibration status';
            app.TextArea_3 = uitextarea(app.CalibrationstatusPanel);
            app.TextArea_3.Editable = 'off';
            app.TrackingTab_2 = uitab(app.TabGroup);
            app.TrackingTab_2.Title = 'Tracking';
            app.TrackingconfigurationPanel = uipanel(app.TrackingTab_2);
            app.TrackingconfigurationPanel.Title = 'Tracking configuration';
            app.TargetspeedHzSpinnerLabel = uilabel(app.TrackingconfigurationPanel);
            app.TargetspeedHzSpinnerLabel.Text = 'Target speed (Hz)';
            app.TargetspeedHzSpinner = uispinner(app.TrackingconfigurationPanel);
            app.TargetspeedHzSpinner.Step = 5;
            app.TargetspeedHzSpinner.Limits = [1 200];
            app.TargetspeedHzSpinner.Value = 30;
            app.StarttrackingButton = uibutton(app.TrackingconfigurationPanel, 'state');
            app.StarttrackingButton.ValueChangedFcn = createCallbackFcn(app, @StarttrackingButtonValueChanged, true);
            app.StarttrackingButton.Enable = 'off';
            app.StarttrackingButton.Text = 'Start tracking';
            app.StatusTextAreaLabel = uilabel(app.TrackingconfigurationPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.TrackingconfigurationPanel);
            app.StoptrackingButton = uibutton(app.TrackingconfigurationPanel, 'push');
            app.StoptrackingButton.ButtonPushedFcn = createCallbackFcn(app, @StoptrackingButtonPushed, true);
            app.StoptrackingButton.Enable = 'off';
            app.StoptrackingButton.Text = 'Stop tracking';
            app.TrackinginformationPanel = uipanel(app.TrackingTab_2);
            app.TrackinginformationPanel.Title = 'Tracking information';
            app.TrackingFrequency = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.TrackingFrequency.ValueDisplayFormat = '%.0f';
            app.TrackingFrequency.Editable = 'off';
            app.SensorselectDropDown_7Label = uilabel(app.TrackinginformationPanel);
            app.SensorselectDropDown_7Label.Text = 'Sensor select';
            app.SensorselectDropDown_7 = uidropdown(app.TrackinginformationPanel);
            app.SensorselectDropDown_7.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_7.ValueChangedFcn = createCallbackFcn(app, @SensorselectDropDown_7ValueChanged, true);
            app.SensorselectDropDown_7.Value = 'Select';
            app.XmmEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.XmmEditField.Editable = 'off';
            app.YmmEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.YmmEditField.Editable = 'off';
            app.ZmmEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.ZmmEditField.Editable = 'off';
            app.radiansEditFieldLabel = uilabel(app.TrackinginformationPanel);
            app.radiansEditFieldLabel.Text = 'θ (radians)';
            app.radiansEditField = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.radiansEditField.Editable = 'off';
            app.radiansEditField_2Label = uilabel(app.TrackinginformationPanel);
            app.radiansEditField_2Label.Text = 'φ (radians)';
            app.radiansEditField_2 = uieditfield(app.TrackinginformationPanel, 'numeric');
            app.radiansEditField_2.Editable = 'off';
            app.TrackingfrequencyHzLabel = uilabel(app.TrackinginformationPanel);
            app.TrackingfrequencyHzLabel.Text = 'Tracking frequency (Hz)';
            app.OpenIGTLinkTab_2 = uitab(app.TabGroup);
            app.OpenIGTLinkTab_2.Title = 'OpenIGTLink';
            app.NetworksettingsPanel = uipanel(app.OpenIGTLinkTab_2);
            app.NetworksettingsPanel.Title = 'Network settings';
            app.AddressEditFieldLabel = uilabel(app.NetworksettingsPanel);
            app.AddressEditFieldLabel.Text = 'Address';
            app.AddressEditField = uieditfield(app.NetworksettingsPanel, 'text');
            app.AddressEditField.Value = '127.0.0.1';
            app.PortEditFieldLabel = uilabel(app.NetworksettingsPanel);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.NetworksettingsPanel, 'numeric');
            app.PortEditField.ValueDisplayFormat = '%.0f';
            app.PortEditField.Value = 18944;
            app.StreamButton = uibutton(app.NetworksettingsPanel, 'push');
            app.StreamButton.Enable = 'off';
            app.StreamButton.Text = 'Stream';
            app.ConnectButton_3 = uibutton(app.NetworksettingsPanel, 'state');
            app.ConnectButton_3.ValueChangedFcn = createCallbackFcn(app, @ConnectButton_3ValueChanged, true);
            app.ConnectButton_3.Text = 'Connect';
            app.ConnectionstatusPanel = uipanel(app.OpenIGTLinkTab_2);
            app.ConnectionstatusPanel.Title = 'Connection status';
            app.TextArea_4 = uitextarea(app.ConnectionstatusPanel);
            app.TextArea_4.Editable = 'off';
            app.ApplytransformPanel = uipanel(app.OpenIGTLinkTab_2);
            app.ApplytransformPanel.Title = 'Apply transform';
            app.UITable_3 = uitable(app.ApplytransformPanel);
            app.UITable_3.ColumnName = {'x'; 'y'; 'z'; 't'};
            app.UITable_3.RowName = {};
            app.UITable_3.ColumnEditable = true;
            app.SensorselectDropDown_8Label = uilabel(app.ApplytransformPanel);
            app.SensorselectDropDown_8Label.Text = 'Sensor select';
            app.SensorselectDropDown_8 = uidropdown(app.ApplytransformPanel);
            app.SensorselectDropDown_8.Items = {'Select', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.SensorselectDropDown_8.Value = 'Select';
            app.FlipZButton = uibutton(app.ApplytransformPanel, 'push');
            app.FlipZButton.ButtonPushedFcn = createCallbackFcn(app, @FlipZButtonPushed, true);
            app.FlipZButton.Text = 'Flip Z';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.TabGroup2 = uitabgroup(app.ToolsTab);
            app.SampleTab_2 = uitab(app.TabGroup2);
            app.SampleTab_2.Title = 'Sample';
            app.FFTTab = uitab(app.TabGroup2);
            app.FFTTab.Title = 'FFT';
            app.PositionTab = uitab(app.TabGroup2);
            app.PositionTab.Title = 'Position';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Label_3 = uilabel(app.AboutTab);
            app.Label_3.Text = {'Anser EMT: An open source electromagnetic tracking platform'; ''};
            app.Label_4 = uilabel(app.AboutTab);
            app.Label_4.Text = {'To acknowledge Anser EMT as a platform, please cite the OpenEMT website '; 'and the following publications when publishing work that uses or incorporates Anser EMT:'};
            app.Label2 = uilabel(app.AboutTab);
            app.Label2.Text = {'Copyright (c) 2017, H. A. Jaeger, K. O'' Donoghue'; 'All rights reserved.'; 'This code is licensed under the BSD 3-Clause License.'; ''; ''};
            app.Label_5 = uilabel(app.AboutTab);
            app.Label_5.Text = {'Jaeger H.A., Franz A.M., O'' Donoghue K., Seitel., Trauzettel F., Maier-Hein L., Cantillon-Murphy P.'; '"Anser EMT: the first open-source electromagnetic tracking platform for image-guided interventions".'; 'International Journal of Computer Assisted Radiology and Surgery, June 2017, 12(6):1059–1067.'; 'PMID: 28357627, DOI: 10.1007/s11548-017-1568-7'; ''};
            app.Label_6 = uilabel(app.AboutTab);
            app.Label_6.Text = {'This work was supported by the Irish Health Research Board, Science Foundation Ireland'; 'and the New Horizon Framework Programme Grant Agreement (details in paper)'; 'The authors would also like to acknowledge the German Cancer Research Center (DKFZ), Heidelberg'; 'and the Institute of Image-Guided Surgery (IHU), Strasbourg in supporting this work.'};
            app.AnserEMTLabel = uilabel(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.AnserEMTLabel.Text = 'Anser EMT';
            app.AgraphicaluserinterfacefortheAnserEMTplatformLabel = uilabel(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure);
            app.AgraphicaluserinterfacefortheAnserEMTplatformLabel.Text = 'A graphical user interface for the Anser EMT platform.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Anser

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)

            % Execute the startup function
            runStartupFcn(app, @AnserStartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnserEMTAnopensourceelectromagnetictrackingplatformUIFigure)
        end
    end
end